classdef ImageNorm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        StainNormalizationTab           matlab.ui.container.Tab
        TargetPanel                     matlab.ui.container.Panel
        ATargetImageButton              matlab.ui.control.Button
        TargetFolderButton              matlab.ui.control.Button
        ComputeMeanButton               matlab.ui.control.Button
        ImageAnalysisToolTextAreaLabel  matlab.ui.control.Label
        ImageAnalysisToolTextArea       matlab.ui.control.TextArea
        BackgroundButtonGroup           matlab.ui.container.ButtonGroup
        WhiteButton_2                   matlab.ui.control.RadioButton
        BlackButton_2                   matlab.ui.control.RadioButton
        TargetReferenceLabel            matlab.ui.control.Label
        SourceImageLabel                matlab.ui.control.Label
        NormalizedImageLabel            matlab.ui.control.Label
        SourcePanel                     matlab.ui.container.Panel
        ASourceImageButton              matlab.ui.control.Button
        SourceFolderButton              matlab.ui.control.Button
        NormalizationPanel              matlab.ui.container.Panel
        NormFolderButton                matlab.ui.control.Button
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        RunButton                       matlab.ui.control.Button
        BackgroundColorButtonGroup      matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        WhiteButton                     matlab.ui.control.RadioButton
        BlackButton                     matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        CommingSoonTab                  matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        path_tar_img = ''; % Description
        path_tar_dir = '';
        path_sor_img = '';
        path_sor_dir = '';
        path_norm = '';
        
        mask_tar = '';
        mask_sor = '';
        
        norm_tar_img = '';

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ATargetImageButton
        function ATargetImageButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Loading Target:  Please wait...';
            [fileName, filePath] = uigetfile({'*.*','All Files'}, 'Select a reference image');
            if app.WhiteButton_2.Value
                val = 1;
            elseif app.BlackButton_2.Value
                val = 2;
            end
            
            if isequal(fileName,0) || isequal(filePath,0)
                disp('User clicked Cancel.')
                return;
            else
                % Remove title, axis labels, and tick labels
                title(app.UIAxes, [])
                xlabel(app.UIAxes, [])
                ylabel(app.UIAxes, [])
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                
                % Display image and stretch to fill axes
                target = imread(fullfile(filePath, fileName));
                I = imshow(target, 'parent', app.UIAxes);
%                 I = imshow(target, 'parent', app.UIAxes, ...
%                     'XData', [1 app.UIAxes.Position(3)], ...
%                     'YData', [1 app.UIAxes.Position(4)]);
%                 
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                % mask
                imagePath = fullfile(filePath, fileName);
                mask = genMask(imagePath, val); % mask: cell
                % save
                app.path_tar_img = fullfile(filePath, fileName);
                app.mask_tar = mask;
                app.path_tar_dir = [];
                app.ImageAnalysisToolTextArea.Value = 'Loading Target:  Done!';
            end

        end

        % Button pushed function: TargetFolderButton
        function TargetFolderButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Loading Target:  Please wait...';
            fileLoc = uigetdir;
            if app.WhiteButton_2.Value
                val = 1;
            elseif app.BlackButton_2.Value
                val = 2;
            end
            
            if isequal(fileLoc,0)
                disp('User clicked Cancel.')
                return;
            else
                % Remove title, axis labels, and tick labels
                title(app.UIAxes, [])
                xlabel(app.UIAxes, [])
                ylabel(app.UIAxes, [])
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                
                % Display image (and stretch to fill axes)
                d = dir(fileLoc);
                dirFlags = [d.isdir];
                d = d(~dirFlags); % files only
                
                target = imread(fullfile(fileLoc, d(1).name));
                I = imshow(target, 'parent', app.UIAxes);
%                 I = imshow(target, 'parent', app.UIAxes, ...
%                     'XData', [1 app.UIAxes.Position(3)], ...
%                     'YData', [1 app.UIAxes.Position(4)]);
                
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
%                 % mask % mask will be generated in "Compute Mean"
%                 imagePath = fileLoc;
%                 mask = genMask(imagePath, val); % mask1: cell
                
                % save
                app.path_tar_dir = fileLoc;
                app.path_tar_img = [];
                app.path_sor_dir = fileLoc;
%                 app.mask_tar = mask;
                app.ImageAnalysisToolTextArea.Value = 'Loading Target:  Done!';
            end            
        end

        % Button pushed function: ASourceImageButton
        function ASourceImageButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Loading Source:  Please wait...';
            [fileName, filePath] = uigetfile({'*.*','All Files'}, 'Select a source image');
            if app.WhiteButton_2.Value
                val = 1;
            elseif app.BlackButton_2.Value
                val = 2;
            end
            
            
            if isequal(fileName,0) || isequal(filePath,0)
                disp('User clicked Cancel.')
                return;
            else
                % Remove title, axis labels, and tick labels
                title(app.UIAxes_2, [])
                xlabel(app.UIAxes_2, [])
                ylabel(app.UIAxes_2, [])
                app.UIAxes_2.XAxis.TickLabels = {};
                app.UIAxes_2.YAxis.TickLabels = {};
                
                % Display image and stretch to fill axes
                source = imread(fullfile(filePath, fileName));
                I = imshow(source, 'parent', app.UIAxes_2);
%                 I = imshow(source, 'parent', app.UIAxes_2, ...
%                     'XData', [1 app.UIAxes_2.Position(3)], ...
%                     'YData', [1 app.UIAxes_2.Position(4)]);
                
                % Set limits of axes
                app.UIAxes_2.XLim = [0 I.XData(2)];
                app.UIAxes_2.YLim = [0 I.YData(2)];
                
                % mask
                imagePath = fullfile(filePath, fileName);
                mask = genMask(imagePath, val); % mask: cell
                
                % save
                app.path_sor_img = fullfile(filePath, fileName);
                app.mask_sor = mask;
                app.path_sor_dir = [];
                app.ImageAnalysisToolTextArea.Value = 'Loading Source:  Done!';
            end
        end

        % Button pushed function: SourceFolderButton
        function SourceFolderButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Loading Source:  Please wait...';
            fileLoc = uigetdir;
            if app.WhiteButton_2.Value
                val = 1;
            elseif app.BlackButton_2.Value
                val = 2;
            end
            
            if isequal(fileLoc,0)
                disp('User clicked Cancel.')
                return;
            else
                % Remove title, axis labels, and tick labels
                title(app.UIAxes_2, [])
                xlabel(app.UIAxes_2, [])
                ylabel(app.UIAxes_2, [])
                app.UIAxes_2.XAxis.TickLabels = {};
                app.UIAxes_2.YAxis.TickLabels = {};
                
                % Display image and stretch to fill axes
                d = dir(fileLoc);
                dirFlags = [d.isdir];
                d = d(~dirFlags); % files only
                
                source = imread(fullfile(fileLoc, d(end).name));
                I = imshow(source, 'parent', app.UIAxes_2);
%                 I = imshow(source, 'parent', app.UIAxes_2, ...
%                     'XData', [1 app.UIAxes_2.Position(3)], ...
%                     'YData', [1 app.UIAxes_2.Position(4)]);
                
                % Set limits of axes
                app.UIAxes_2.XLim = [0 I.XData(2)];
                app.UIAxes_2.YLim = [0 I.YData(2)];
                
                % mask
                imagePath = fileLoc;
                mask = genMask(imagePath, val); % mask2: cell
                
                % save
                app.path_sor_dir = fileLoc;
                app.mask_sor = mask;
                app.path_sor_img = [];
                app.ImageAnalysisToolTextArea.Value = 'Loading Source:  Done!';
            end
        end

        % Button pushed function: NormFolderButton
        function NormFolderButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Save folder selected!';
            fileLoc = uigetdir;
            if isequal(fileLoc,0)
                disp('User clicked Cancel.')
                return;
            else
                % Remove title, axis labels, and tick labels
                title(app.UIAxes_3, [])
                xlabel(app.UIAxes_3, [])
                ylabel(app.UIAxes_3, [])
                app.UIAxes_3.XAxis.TickLabels = {};
                app.UIAxes_3.YAxis.TickLabels = {};
                
                % Display source image and stretch to fill axes
                if exist(app.path_sor_img, 'file') || ~isempty(app.path_sor_img)
                    source = imread(app.path_sor_img); % source image
                 elseif exist(app.path_sor_dir, 'file') || ~isempty(app.path_sor_dir)
                    d_img = dir(app.path_sor_dir);
                    dirFlags = [d_img.isdir];
                    d_img = d_img(~dirFlags); % files only
                
                    source = imread(fullfile(app.path_sor_dir, d_img(end).name)); % source image
                elseif exist(app.path_tar_dir, 'file') || ~isempty(app.path_tar_dir)
                    d_img = dir(app.path_tar_dir);
                    dirFlags = [d_img.isdir];
                    d_img = d_img(~dirFlags); % files only
                    source = imread(fullfile(app.path_tar_dir, d_img(end).name)); % source image
                end
                I = imshow(source, 'parent', app.UIAxes_3);
%                 I = imshow(source, 'parent', app.UIAxes_3, ...
%                     'XData', [1 app.UIAxes_3.Position(3)], ...
%                     'YData', [1 app.UIAxes_3.Position(4)]);
                
                % Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];
                
                % save
                app.path_norm = fileLoc;

            end
        end

        % Button pushed function: ComputeMeanButton
        function ComputeMeanButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Compute Mean:  Please wait...';
            
            if app.WhiteButton_2.Value
                val = 1;
            elseif app.BlackButton_2.Value
                val = 2;
            end
            
            if exist(app.path_tar_dir,'file') || ~isempty(app.path_tar_dir)
                fileLoc = app.path_tar_dir;
            else
                fileLoc = app.path_tar_img;
                mask = genMask(fileLoc, val);
                app.mask_tar = mask;
                % text box
                app.ImageAnalysisToolTextArea.Value = 'Compute mean:  Done!';
            end
                      
                        
            if isfolder(fileLoc)
 
                [parentDir, currentDir, ~] = fileparts(fileLoc);
                saveNormFolder = fullfile(parentDir, [currentDir '_norm_save']);
                mkdir(saveNormFolder)
                
                % generage a target
                mask = genMask(fileLoc, val);
                [NormTarImgPath, avg_mt, avg_st] = NormReinhard_Target(fileLoc, mask); 
                
                % show an image in Target
                tarImage = imread(NormTarImgPath);
                I = imshow(tarImage, 'parent', app.UIAxes);
%                 I = imshow(tarImage, 'parent', app.UIAxes, ...
%                     'XData', [1 app.UIAxes.Position(3)], ...
%                     'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                
                % text box
                app.ImageAnalysisToolTextArea.Value = 'Compute mean:  Done!';
                
                % save
                app.norm_tar_img = NormTarImgPath;
                %app.mask_dir = Mask;
                
            end
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ImageAnalysisToolTextArea.Value = 'Run:    Please wait!';
            fileLoc = app.path_norm;
            
            if app.BlackButton.Value
                val = 2;
            elseif app.WhiteButton.Value
                val = 1;
            else
                val = 0;
            end
            
            if isfolder(fileLoc)
                if exist(app.norm_tar_img,'file') || ~isempty(app.norm_tar_img)
                    tarImage = app.norm_tar_img; % reference target image in save folder 
                else
                    tarImage = app.path_tar_img; % target image for normalization
                end
                
                if exist(app.path_sor_img,'file') || ~isempty(app.path_sor_img)
                    fileLoc = stainNorm(app.path_sor_img, fileLoc, tarImage, app.mask_sor, app.mask_tar, val);
                elseif exist(app.path_sor_dir,'file') || ~isempty(app.path_sor_dir)
                    fileLoc = stainNorm(app.path_sor_dir, fileLoc, tarImage, app.mask_sor, app.mask_tar, val);
                end
                
                % show an image after normalization
                normImage = imread(fileLoc); % the last image
%                 d_img = dir(fullfile(app.path_norm, '*g'));
%                 normImage = imread(fullfile(app.path_norm, d_img(1).name)); % source image
                I = imshow(normImage, 'parent', app.UIAxes_3);
%                 I = imshow(normImage, 'parent', app.UIAxes_3, ...
%                     'XData', [1 app.UIAxes_3.Position(3)], ...
%                     'YData', [1 app.UIAxes_3.Position(4)]);
                % Set limits of axes
                app.UIAxes_3.XLim = [0 I.XData(2)];
                app.UIAxes_3.YLim = [0 I.YData(2)];
                app.ImageAnalysisToolTextArea.Value = 'Done!';
            end
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StainNormalizationTab = uitab(app.TabGroup);
            app.StainNormalizationTab.Title = 'Stain Normalization';
            app.TargetPanel = uipanel(app.StainNormalizationTab);
            app.TargetPanel.Title = 'Target';
            app.ATargetImageButton = uibutton(app.TargetPanel, 'push');
            app.ATargetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ATargetImageButtonPushed, true);
            app.ATargetImageButton.Text = 'A Target Image';
            app.TargetFolderButton = uibutton(app.TargetPanel, 'push');
            app.TargetFolderButton.ButtonPushedFcn = createCallbackFcn(app, @TargetFolderButtonPushed, true);
            app.TargetFolderButton.Text = 'Target Folder';
            app.ComputeMeanButton = uibutton(app.TargetPanel, 'push');
            app.ComputeMeanButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeMeanButtonPushed, true);
            app.ComputeMeanButton.Text = 'Compute Mean';
            app.ImageAnalysisToolTextAreaLabel = uilabel(app.TargetPanel);
            app.ImageAnalysisToolTextAreaLabel.Text = 'Image Analysis Tool';
            app.ImageAnalysisToolTextArea = uitextarea(app.TargetPanel);
            app.ImageAnalysisToolTextArea.Value = {'V1.0: July 2020'; 'Univ. of Michigan'; 'Ann Arbor, MI'};
            app.BackgroundButtonGroup = uibuttongroup(app.TargetPanel);
            app.BackgroundButtonGroup.Title = 'Background';
            app.WhiteButton_2 = uiradiobutton(app.BackgroundButtonGroup);
            app.WhiteButton_2.Text = 'White';
            app.WhiteButton_2.Value = true;
            app.BlackButton_2 = uiradiobutton(app.BackgroundButtonGroup);
            app.BlackButton_2.Text = 'Black';
            app.TargetReferenceLabel = uilabel(app.StainNormalizationTab);
            app.TargetReferenceLabel.Text = 'Target / Reference';
            app.SourceImageLabel = uilabel(app.StainNormalizationTab);
            app.SourceImageLabel.Text = 'Source Image';
            app.NormalizedImageLabel = uilabel(app.StainNormalizationTab);
            app.NormalizedImageLabel.Text = 'Normalized Image';
            app.SourcePanel = uipanel(app.StainNormalizationTab);
            app.SourcePanel.Title = 'Source';
            app.ASourceImageButton = uibutton(app.SourcePanel, 'push');
            app.ASourceImageButton.ButtonPushedFcn = createCallbackFcn(app, @ASourceImageButtonPushed, true);
            app.ASourceImageButton.Text = 'A Source Image';
            app.SourceFolderButton = uibutton(app.SourcePanel, 'push');
            app.SourceFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SourceFolderButtonPushed, true);
            app.SourceFolderButton.Text = 'Source Folder';
            app.NormalizationPanel = uipanel(app.StainNormalizationTab);
            app.NormalizationPanel.Title = 'Normalization';
            app.NormFolderButton = uibutton(app.NormalizationPanel, 'push');
            app.NormFolderButton.ButtonPushedFcn = createCallbackFcn(app, @NormFolderButtonPushed, true);
            app.NormFolderButton.Text = 'Norm Folder';
            app.AlgorithmDropDownLabel = uilabel(app.NormalizationPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.NormalizationPanel);
            app.AlgorithmDropDown.Items = {'Reinhard', 'Option 2', 'Option 3', 'Option 4'};
            app.AlgorithmDropDown.Value = 'Reinhard';
            app.RunButton = uibutton(app.NormalizationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BackgroundColorButtonGroup = uibuttongroup(app.NormalizationPanel);
            app.BackgroundColorButtonGroup.Title = 'Background Color';
            app.NoneButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.WhiteButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.WhiteButton.Text = 'White';
            app.BlackButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.BlackButton.Text = 'Black';
            app.UIAxes = uiaxes(app.StainNormalizationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.StainNormalizationTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.StainNormalizationTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.CommingSoonTab = uitab(app.TabGroup);
            app.CommingSoonTab.Title = 'Comming Soon';
            app.Image = uiimage(app.CommingSoonTab);
            app.Image.ImageSource = 'Under_Construction_02b.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageNorm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end